classdef basic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        PlotControlsTab               matlab.ui.container.Tab
        WhatareweplottingButtonGroup  matlab.ui.container.ButtonGroup
        SquaresButton                 matlab.ui.control.RadioButton
        NeedlesButton                 matlab.ui.control.RadioButton
        BestagonsButton               matlab.ui.control.RadioButton
        HOWMANYSQUARESSpinnerLabel    matlab.ui.control.Label
        HOWMANYSQUARESSpinner         matlab.ui.control.Spinner
        HOWMANYPLANKSSliderLabel      matlab.ui.control.Label
        HOWMANYPLANKSSlider           matlab.ui.control.Slider
        UIControlsTab                 matlab.ui.container.Tab
        c1931370Label                 matlab.ui.control.Label
        OutEstimateLabel              matlab.ui.control.Label
        PlottingStatusLampLabel       matlab.ui.control.Label
        PlottingStatusLamp            matlab.ui.control.Lamp
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
		S	=	1					% Scale factor
        N	=	1000				% Number of needles
		NoP	=	5					% Number of planks
		D							% Distance between planks
		SL							% Length of square side
		
		% Patch object
		p
		
		% State
		sq_angles
		xc
		yc
		xcr
		ycr
		
		planks
    end
    
    methods (Access = private)
        
		function updatePlankCount(app, plankCount)
			app.NoP = plankCount;
			app.D =	app.S / (app.NoP - 1);
			
			updateSquarePlot(app, true);
		end
        
		function updateSquareCount(app, squareCount)
			app.N = squareCount;
			app.sq_angles = rand(1, app.N) * 360;
			app.xc = rand(1, app.N);
			app.yc = rand(1, app.N);
			
			updateSquarePlot(app, true);
		end
		
		function updateSquarePlot(app, plankCountChanged)
			cla(app.UIAxes,'reset')
			% delete(app.p);
			
			if plankCountChanged == true 
				updatePlotFloor(app);
			end
			
			app.xcr = [...
				app.SL * cosd(app.sq_angles) + app.SL * sind(app.sq_angles) + app.xc;...
				app.SL * cosd(app.sq_angles) - app.SL * sind(app.sq_angles) + app.xc;...
				-app.SL * cosd(app.sq_angles) - app.SL * sind(app.sq_angles) + app.xc;...
				-app.SL * cosd(app.sq_angles) + app.SL * sind(app.sq_angles) + app.xc;...
			];
			app.ycr = [...
				-app.SL * sind(app.sq_angles) + app.SL * cosd(app.sq_angles) + app.yc;...
				-app.SL * sind(app.sq_angles) - app.SL * cosd(app.sq_angles) + app.yc;...
				app.SL * sind(app.sq_angles) - app.SL * cosd(app.sq_angles) + app.yc;...
				app.SL * sind(app.sq_angles) + app.SL * cosd(app.sq_angles) + app.yc;...
			];
			
			calculateSquarePi(app);
			
			% Plot all the things
			app.p = patch(app.UIAxes, app.xcr, app.ycr, 'red');
		end
		
		function calculateSquarePi(app)
			n = 0;
			disp(app.xcr);
			n = n + sum(floor(app.xcr(1, :)/app.D) ~= floor(app.xcr(2, :)/app.D)) + ...
					sum(floor(app.xcr(2, :)/app.D) ~= floor(app.xcr(3, :)/app.D)) + ...
					sum(floor(app.xcr(3, :)/app.D) ~= floor(app.xcr(4, :)/app.D)) + ...
					sum(floor(app.xcr(4, :)/app.D) ~= floor(app.xcr(1, :)/app.D));
			t = app.N*4;
			
			pi_estimate = t/n;
			UIUpdateOutEstimate(app, ['Pi Estimate: ' num2str(pi_estimate)]);
		end
		
		function UIUpdateOutEstimate(app, value)
			set(app.OutEstimateLabel, 'Text', value);
		end
		
		function updatePlotFloor(app)
			app.planks = 1:app.NoP;
			c = 0;
			for i = 0:app.D:app.S
				c = c+1;
				app.planks(c) = xline(app.UIAxes, i,  '-', 'LineWidth', 2);
			end
		end
	end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.D	=	app.S / app.NoP;
			app.SL	=	app.D / 4;
        end

        % Value changed function: HOWMANYSQUARESSpinner
        function HOWMANYSQUARESSpinnerValueChanged(app, event)
            value = ceil(app.HOWMANYSQUARESSpinner.Value);
            updateSquareCount(app, value);
        end

        % Value changed function: HOWMANYPLANKSSlider
        function HOWMANYPLANKSSliderValueChanged(app, event)
            value = app.HOWMANYPLANKSSlider.Value;
			[~, minVal] = min(abs(value - event.Source.MajorTicks(:)));
			event.Source.Value = event.Source.MajorTicks(minVal);
			
			updatePlankCount(app, minVal);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlotControlsTab = uitab(app.TabGroup);
            app.PlotControlsTab.Title = 'Plot Controls';
            app.WhatareweplottingButtonGroup = uibuttongroup(app.PlotControlsTab);
            app.WhatareweplottingButtonGroup.Title = 'What are we plotting?';
            app.SquaresButton = uiradiobutton(app.WhatareweplottingButtonGroup);
            app.SquaresButton.Text = 'Squares';
            app.SquaresButton.Value = true;
            app.NeedlesButton = uiradiobutton(app.WhatareweplottingButtonGroup);
            app.NeedlesButton.Text = 'Needles';
            app.BestagonsButton = uiradiobutton(app.WhatareweplottingButtonGroup);
            app.BestagonsButton.Text = 'Bestagons';
            app.HOWMANYSQUARESSpinnerLabel = uilabel(app.PlotControlsTab);
            app.HOWMANYSQUARESSpinnerLabel.Text = 'HOW MANY SQUARES';
            app.HOWMANYSQUARESSpinner = uispinner(app.PlotControlsTab);
            app.HOWMANYSQUARESSpinner.Limits = [0 10000000];
            app.HOWMANYSQUARESSpinner.ValueChangedFcn = createCallbackFcn(app, @HOWMANYSQUARESSpinnerValueChanged, true);
            app.HOWMANYSQUARESSpinner.Value = 1000;
            app.HOWMANYPLANKSSliderLabel = uilabel(app.PlotControlsTab);
            app.HOWMANYPLANKSSliderLabel.Text = 'HOW MANY PLANKS';
            app.HOWMANYPLANKSSlider = uislider(app.PlotControlsTab);
            app.HOWMANYPLANKSSlider.Limits = [1 10];
            app.HOWMANYPLANKSSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.HOWMANYPLANKSSlider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.HOWMANYPLANKSSlider.ValueChangedFcn = createCallbackFcn(app, @HOWMANYPLANKSSliderValueChanged, true);
            app.HOWMANYPLANKSSlider.MinorTicks = [];
            app.HOWMANYPLANKSSlider.Value = 5;
            app.UIControlsTab = uitab(app.TabGroup);
            app.UIControlsTab.Title = 'UI Controls';
            app.c1931370Label = uilabel(app.UIFigure);
            app.c1931370Label.Text = 'c1931370';
            app.OutEstimateLabel = uilabel(app.UIFigure);
            app.OutEstimateLabel.Text = 'Pi Estimate: N/A';
            app.PlottingStatusLampLabel = uilabel(app.UIFigure);
            app.PlottingStatusLampLabel.Text = 'Plotting Status';
            app.PlottingStatusLamp = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Floor')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end